classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function RandomPosCallBack(app, event)
        %app.Button.Position = [rand(1)*app.UIFigure.Position(1,1) rand(1)*app.UIFigure.Position(1,2)  rand(1)*app.UIFigure.Position(1,3) 22];
            app.Button.Position = [rand(1)*app.UIFigure.Position(1,1)+app.Button.Position(1,1) rand(1)*app.UIFigure.Position(1,2)+app.Button.Position(1,2) rand(1)*app.UIFigure.Position(1,3)+app.Button.Position(1,3) app.Button.Position(1,4)]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @RandomPosCallBack, true);
            app.Button.Text = {'Нажми меня '; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end