classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        Image             matlab.ui.control.Image
        goButton          matlab.ui.control.Button
        Image2            matlab.ui.control.Image
        Button_2          matlab.ui.control.Button
        nLabel            matlab.ui.control.Label
        nEditField        matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField2Label   matlab.ui.control.Label
        EditField2        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             [file,path] = uigetfile('*.png','*.jpeg','*.jpg');
             file_name = strcat(path,file);
             app.Image.ImageSource = file_name; 
             app.EditField2.Value = file_name;
             
        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
%                  [file,path] = uigetfile('*.png','*.jpeg','*.jpg');
%                  file_name = strcat(path,file);
%                  app.Image.ImageSource = file_name; 
%                  app.TextArea.Value = file_name;
                 fileName = app.EditField2.Value;
                 img = imread(fileName);
                 
                 ch = app.EditField_3.Value;
                 main_ch = app.EditField_2.Value;
                 n = app.nEditField.Value;


                 app.Image2.ImageSource = go(img,ch,main_ch,n);


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           
            img = app.Image2.ImageSource;
            imwrite(img, 'result.jpg')
%             saveas(img,'file_to_save','png')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'OpenButton';
            app.Button.Text = 'Открыть файл';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'preview';
            app.goButton = uibutton(app.UIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'go';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Tag = 'result_image';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = 'SafeButton';
            app.Button_2.Text = 'Сохранить файл';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'Ведите n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Приоритетный канал';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Tag = 'mainCh';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Visible = 'off';
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end